classdef SNATCH_IT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Container                     matlab.ui.container.Panel
        SettingsPanel                 matlab.ui.container.Panel
        LengthSpinner                 matlab.ui.control.Spinner
        LengthLabel                   matlab.ui.control.Label
        ModeLabel                     matlab.ui.control.Label
        ModeDD                        matlab.ui.control.DropDown
        SettingsLabel                 matlab.ui.control.Label
        HoardPanel                    matlab.ui.container.Panel
        RemainingTilesEditField       matlab.ui.control.EditField
        RemainingTilesEditFieldLabel  matlab.ui.control.Label
        NewWordDD                     matlab.ui.control.DropDown
        NewTileButton                 matlab.ui.control.Button
        NewWordEditField              matlab.ui.control.EditField
        ValidWordLamp                 matlab.ui.control.Lamp
        HoardLabel                    matlab.ui.control.Label
        NewGameButton                 matlab.ui.control.Button
        PoolPanel                     matlab.ui.container.Panel
        PoolLabel                     matlab.ui.control.Label
    end


    properties (Access = private)
    tileBag     % cell array to hold tiles
    pool        % cell array to hold revealed tiles
    hoard       % cell array to hold 'hoard' of words
    dictionary  % cell array to hold legal/permitted English words

end


methods (Access = private)
    %app.NewWordEditField.KeyReleaseFcn = @(src, event); 
    %NewWordEditFieldKeyPressed(app, event);
    
%=== SUBMIT WORD AFTER HITTING ENTER KEY
% THIS NEEDS WORK, NOT FUNCTIONING ATM

function NewWordEditFieldKeyPressed(app, event)
    if strcmp(event.Key, 'return') % Check if Enter key is pressed
        NewWord = app.NewWordEditField.Value;
        submitWord(app, NewWord);

    end
end


%=== ADD WORD TO HOARD AFTER SUCESSFUL SUBMISSION
function submitWord(app, word)
    % Convert word to uppercase
    word = upper(word);


    %== CONFIRM VALIDITY
    % proceed only if word is 1) in dictionary and 2) min length or more
    if isValidWord(app, word)
        % Split word into individual letters
        letters = cellstr(word');

        %=== FOR NEW WORDS:
        if strcmp(app.NewWordDD.Value, 'NEW WORD')
            
            % Check if all letters in the word are in the pool
            for i = 1:length(letters)
                idx = find(strcmp(app.pool, letters{i}), 1);
                if isempty(idx)
                    app.ValidWordLamp.Color = 'red';
                    return;
                end
                app.pool(idx) = [];
            end

            % Add new word to hoard
            app.hoard{end+1} = word;


        %=== FOR STOLEN WORDS:
        elseif strcmp(app.NewWordDD.Value, 'STEAL WORD')
        
            % Find a word in the hoard that could be transformed
            for i = 1:numel(app.hoard)
                hoardWord = app.hoard{i};
                hoardLetters = cellstr(hoardWord');
                
                % Check if new word could form from hoard + pool letters
                canForm = true;
                remainingLetters = letters;
                for j = 1:length(hoardLetters)
                    idx = find(strcmp(remainingLetters, hoardLetters{j}), 1);
                    if ~isempty(idx)
                        remainingLetters(idx) = [];
                    else
                        canForm = false;
                        break;
                    end
                end


                % If word can form, update hoard + pool
                if canForm
                    % Check if the remaining letters are in the pool
                    for j = 1:length(remainingLetters)
                        idx = find(strcmp(app.pool, remainingLetters{j}), 1);
                        if isempty(idx)
                            canForm = false;
                            break;
                        end
                        app.pool(idx) = [];
                    end

                    if canForm
                        % Replace  hoard word
                        app.hoard{i} = word;
                        break;
                    end

                end
                end
                    
   
    %=== UPDATE HOARD DISPLAY (NEW WORD VALID)
    % [Add code here to update the hoard display, if necessary]
   
    else
        % Indicate the word is invalid
        app.ValidWordLamp.Color = 'red';
        return;
    end

    %=== LAMP GREEN (NEW WORD VALID)
    app.ValidWordLamp.Color = 'green';
end            

end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function LoadDictionary(app)

%== LOAD ENABLE DICTIONARY. 
% Located in the directory, but any user needs to access/have it found
% Use this tO determine if user's new word / stolen word is valid
    

% Get directory of the current .mlapp file
appDir = fileparts(mfilename('fullpath'));
    
% Define dictionary file path relative to the app directory
dictionaryFile = fullfile(appDir, 'ENABLE_dictionary.txt');
    
% Check if the dictionary file exists
if exist(dictionaryFile, 'file') ~= 2
    uialert(app.UIFigure, 'Dictionary file not found!', 'File Error');
    return;
end
    
% Read dictionary file
fileID = fopen(dictionaryFile, 'r');
rawDictionary = textscan(fileID, '%s');
fclose(fileID);
    
% Store the dictionary words in a cell array
app.dictionary = rawDictionary{1};


        end

        % Value changed function: ModeDD
        function ModeDDValueChanged(app, event)
  % placeholder...currently need nothing here

        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
    
%== START NEW GAME, USING SELECTED GAME MODE TO DECIDE TILE SET

% Initialize tile bag as an empty cell array
app.tileBag = {};

% Define letter counts for each game mode
selectedSize = app.ModeDD.Value;

switch selectedSize
    case 'Default' 
        % 144 tiles, same as Bananagrams set
        letter_count = struct('E',18, 'A',13, 'I',12, 'O',11, 'T',9, 'R',9, ...
            'N',8, 'D',6, 'S',6, 'U',6, 'L',5, 'G',4, 'B',3, 'C',3, 'F',3, ...
            'H',3, 'M',3, 'P',3, 'V',3, 'W',3, 'Y',3, 'J',2, 'K',2, 'Q',2, ...
            'X',2, 'Z',2);

    case 'Mini'
        % 78 tiles
        letter_count = struct('E',9, 'A',7, 'I',6, 'O',5, 'T',5, 'R',5, ...
            'N',4, 'D',3, 'S',3, 'U',3, 'L',3, 'G',2, 'B',2, 'C',2, 'F',2, ...
            'H',2, 'M',2, 'P',2, 'V',2, 'W',2, 'Y',2, 'J',1, 'K',1, 'Q',1, ...
            'X',1, 'Z',1);

    case 'Wild' % FIX THIS FIX THIS
        % 144 randomized tiles FIX THIS FIX IT FIX THIS
        letter_count = struct('E',20, 'A',15, 'I',15, 'O',14, 'T',12, 'R',12, ...
            'N',10, 'D',8, 'S',8, 'U',8, 'L',7, 'G',6, 'B',5, 'C',5, 'F',5, ...
            'H',5, 'M',5, 'P',5, 'V',4, 'W',4, 'Y',4, 'J',3, 'K',3, 'Q',3, ...
            'X',3, 'Z',3);

    case 'X-Small'
        % 8 tiles, for testing purposes
        letter_count = struct('E',2, 'A',2, 'T',1, 'R',1, 'D',1, 'S',1);

    otherwise
        error('Unknown tile bag size selected');
end


% Prepare letters from selected game mode
letters = fieldnames(letter_count);

for i = 1:numel(letters)
    letter = letters{i};
    count = letter_count.(letter);
    app.tileBag = [app.tileBag, repmat({letter}, 1, count)];
end


% Display the number of remaining tiles
app.RemainingTilesEditField.Value = num2str(numel(app.tileBag));

% Initialize the pool and hoard (will also reset after prev
% game)
app.pool = {};
app.hoard = {};


        end

        % Button pushed function: NewTileButton
        function NewTileButtonPushed(app, event)
    
    %== EACH PUSH ADDS A RANDOM LETTER FROM THE TILE BAG TO THE POOL

    % if any letters are left, add a tile
    if ~isempty(app.tileBag)
        % Randomly select letter from tile bag
        idx = randi(numel(app.tileBag));
        newTile = app.tileBag{idx};

        % Add random tile to pool
        app.pool{end+1} = newTile;
       
        % Remove selected tile from tile bag
        app.tileBag(idx) = [];

        % Display updated number of remaining tiles
        app.RemainingTilesEditField.Value = num2str(numel(app.tileBag));
        

        % Display new tile in pool panel, with space from previous
        % Define the number of items per column
        itemsPerColumn = 9;
        columnWidth = 20;
        rowHeight = 20;


        % Calculate the position for the new label
        column = floor((numel(app.pool) - 1) / itemsPerColumn);
        row = mod((numel(app.pool) - 1), itemsPerColumn);

        xPos = 15 + column * columnWidth;
        yPos = 150 - row * rowHeight;

        uilabel(app.PoolPanel, 'Text', newTile, 'Position', [xPos, yPos, columnWidth, rowHeight]);
        

    else
        % If empty but button pressed, display a message if there are no tiles left in the tile bag
        uialert(app.UIFigure, 'NO MORE TILES REMAINING', 'Tile Bag Empty');
    
        % ** error hopefully not necessary, as empty bag should ->
        % end of game page
    
    end

end


%=== FUNCTION TO CHECK IF A WORD IS VALID
% compare with dictionary ('enable'). Helper function that is
% called for the lamp and for addition of word to hoard.
function isValid = isValidWord(app, word)
    isValid = any(strcmpi(app.dictionary, word));
    % and length must be >= minimum
    

        end

        % Value changed function: NewWordEditField
        function NewWordEditFieldValueChanged(app, event)
    NewWord = app.NewWordEditField.Value;
    
    
    %=== KEY PRESS TO INPUT A WORD
    % If enter key pressed, enter word
    if strcmp(event.Key, 'return') 
        submitWord(app, NewWord);
    end


    %=== VALIDITY OF INPUT WORD ===%

    % if new word is valid, add to hoard + turn lamp green
    if isValidWord(app, NewWord)
        %=== VALIDITY OF NEW WORD
        if strcmp(app.NewWordDD.Value, 'NEW WORD')
            % Add the new word to the hoard
            app.hoard{end+1} = newWord;

            % Update hoard display (RETURN TO THIS)
        
        %=== VALIDITY OF STOLEN WORD
        elseif strcmp(app.NewWordDD.Value, 'STEAL')
            % Handle stealing logic (RETURN TO THIS)

        end
        
        %=== LAMP IF VALID (GREEN)
        % Indicate the word is valid
        app.ValidWordLamp.Color = 'green';


    else
        %=== LAMP IF INVALID (RED)
        % Indicate the word is invalid, and don't add to hoard
        app.ValidWordLamp.Color = 'red';
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Container = uipanel(app.UIFigure);
            app.PoolPanel = uipanel(app.Container);
            app.PoolLabel = uilabel(app.PoolPanel);
            app.PoolLabel.Text = 'POOL';
            app.NewGameButton = uibutton(app.Container, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'NEW GAME';
            app.HoardPanel = uipanel(app.Container);
            app.HoardLabel = uilabel(app.HoardPanel);
            app.HoardLabel.Text = 'HOARD';
            app.ValidWordLamp = uilamp(app.HoardPanel);
            app.NewWordEditField = uieditfield(app.HoardPanel, 'text');
            app.NewWordEditField.ValueChangedFcn = createCallbackFcn(app, @NewWordEditFieldValueChanged, true);
            app.NewTileButton = uibutton(app.HoardPanel, 'push');
            app.NewTileButton.ButtonPushedFcn = createCallbackFcn(app, @NewTileButtonPushed, true);
            app.NewTileButton.Text = 'NEW TILE';
            app.NewWordDD = uidropdown(app.HoardPanel);
            app.NewWordDD.Items = {'NEW WORD', 'STEAL'};
            app.NewWordDD.Placeholder = 'NEW WORD';
            app.NewWordDD.Value = 'NEW WORD';
            app.RemainingTilesEditFieldLabel = uilabel(app.HoardPanel);
            app.RemainingTilesEditFieldLabel.Text = 'Remaining Tiles';
            app.RemainingTilesEditField = uieditfield(app.HoardPanel, 'text');
            app.SettingsPanel = uipanel(app.Container);
            app.SettingsLabel = uilabel(app.SettingsPanel);
            app.SettingsLabel.Text = 'SETTINGS';
            app.ModeDD = uidropdown(app.SettingsPanel);
            app.ModeDD.Items = {'Default', 'Mini', 'Wild', 'X-Small'};
            app.ModeDD.ValueChangedFcn = createCallbackFcn(app, @ModeDDValueChanged, true);
            app.ModeDD.Value = 'Default';
            app.ModeLabel = uilabel(app.SettingsPanel);
            app.ModeLabel.Text = 'GAME MODE';
            app.LengthLabel = uilabel(app.SettingsPanel);
            app.LengthLabel.Text = 'MINIMUM LENGTH';
            app.LengthSpinner = uispinner(app.SettingsPanel);
            app.LengthSpinner.Limits = [3 7];
            app.LengthSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SNATCH_IT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @LoadDictionary)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end